classdef APP_JK_HIL_IHM_v1_5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure_hil                   matlab.ui.Figure
        y1_coord__EditField            matlab.ui.control.NumericEditField
        y1_coordEditFieldLabel         matlab.ui.control.Label
        x1_coord__EditField            matlab.ui.control.NumericEditField
        x1_coordEditFieldLabel         matlab.ui.control.Label
        test_lamp_robot_busy__stateCheckBox  matlab.ui.control.CheckBox
        lamp_robot_busy_state          matlab.ui.control.Lamp
        lamp_robot_busy_stateLabel     matlab.ui.control.Label
        compass_indicator              Aero.ui.control.HeadingIndicator
        compass_indicatorHeadingIndicatorLabel  matlab.ui.control.Label
        turn_indicator                 Aero.ui.control.TurnCoordinator
        turn_indicatorLabel            matlab.ui.control.Label
        heading_speed_indicator        Aero.ui.control.AirspeedIndicator
        heading_speed_indicatorAirspeedIndicatorLabel  matlab.ui.control.Label
        test_heading_speed__indicatorSlider  matlab.ui.control.Slider
        test_heading_speed_indicatorSliderLabel  matlab.ui.control.Label
        test_turn_speed__indicatorSlider  matlab.ui.control.Slider
        test_turn_speed_indicatorSliderLabel  matlab.ui.control.Label
        test_compass__indicatorSlider  matlab.ui.control.Slider
        test_compass_indicatorSliderLabel  matlab.ui.control.Label
        test_turn_acceleration__indicatorSlider  matlab.ui.control.Slider
        test_turn_acceleration_indicatorSliderLabel  matlab.ui.control.Label
        ADD_or_DELETE__DropDown        matlab.ui.control.DropDown
        ADD_or_DELETEDropDownLabel     matlab.ui.control.Label
        Execute__Button                matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        x2_coordEditFieldLabel         matlab.ui.control.Label
        x2_coord__EditField            matlab.ui.control.NumericEditField
        y2_coordEditFieldLabel         matlab.ui.control.Label
        y2_coord__EditField            matlab.ui.control.NumericEditField
        UITable                        matlab.ui.control.Table
        Warningx1x2Label               matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: 
        % test_lamp_robot_busy__stateCheckBox
        function test_lamp_robot_busy__stateCheckBoxValueChanged(app, event)
            lamp_robot_busy_state_value = app.test_lamp_robot_busy__stateCheckBox.Value;
            %%% Light the light when the test_lamp_robot_state is checked :
            %%% when the robot is busy : light red, otherwise light green.
            if lamp_robot_busy_state_value == 1
                app.lamp_robot_busy_state.Color = 'r';
            else
                app.lamp_robot_busy_state.Color = 'g';
            end
        end

        % Value changed function: 
        % test_heading_speed__indicatorSlider
        function test_heading_speed__indicatorSliderValueChanged(app, event)
            heading_speed_indicator_value = app.test_heading_speed__indicatorSlider.Value;
            %%% Update the heading_speed_indicator value with the slider's.
            app.heading_speed_indicator.Airspeed = heading_speed_indicator_value;
        end

        % Value changed function: test_turn_speed__indicatorSlider
        function test_turn_speed__indicatorSliderValueChanged(app, event)
            turn_indicator_acceleration_value = app.test_turn_speed__indicatorSlider.Value;
            %%% Update the turn_indicator acceleration value with the slider's.
            app.turn_indicator.Slip = -turn_indicator_acceleration_value;
        end

        % Value changed function: 
        % test_turn_acceleration__indicatorSlider
        function test_turn_acceleration__indicatorSliderValueChanged(app, event)
            turn_indicator_speed_value = app.test_turn_acceleration__indicatorSlider.Value;
            %%% Update the turn_indicator speed value with the slider's.
            app.turn_indicator.Turn = turn_indicator_speed_value;
        end

        % Value changed function: test_compass__indicatorSlider
        function test_compass__indicatorSliderValueChanged(app, event)
            compass_indicator_value = app.test_compass__indicatorSlider.Value;
            app.compass_indicator.Value = compass_indicator_value;
        end

        % Value changed function: x1_coord__EditField
        function x1_assignment__ValueChangedFcn(app, event)
            global x1_coord__value;
            x1_coord__value = app.x1_coord__EditField.Value;            
        end

        % Value changed function: y1_coord__EditField
        function y1_assignment__ValueChangedFcn(app, event)
            global y1_coord__value;
            y1_coord__value = app.y1_coord__EditField.Value;            
        end

        % Value changed function: x2_coord__EditField
        function x2_assignment__ValueChangedFcn(app, event)
            global x2_coord__value;
            x2_coord__value = app.x2_coord__EditField.Value;            
        end

        % Value changed function: y2_coord__EditField
        function y2_assignment__ValueChangedFcn(app, event)
            global y2_coord__value;
            y2_coord__value = app.y2_coord__EditField.Value;            
        end

        % Value changed function: ADD_or_DELETE__DropDown
        function ADD_or_DELETE__ValueChangedFcn(app, event)
            global ADD_or_DELETE__value;
            ADD_or_DELETE__value = app.ADD_or_DELETE__DropDown.Value;
        end

        % Button pushed function: Execute__Button
        function Execute__ButtonPushedFcn(app, event)
            if ADD_or_DELETE__value == "ADD a line"
                % Save values by exporting them to Matlab, Rosbag and UITable
                % But first...
                x1 = x1_coord__value;
                x2 = x2_coord__value;
                y1 = y1_coord__value;
                y2 = y2_coord__value;
                % Step 1 : Measure the distance between the 2 coordinates-Pythagore
                line_length = sqrt((x2-x1)^2 + (y2-y1)^2);
                D = line_length;
                % Step 2 : for each value of the matrix (map), check the distances
                row_index_max, column_index_max = size(map_matrix);
                % Automation of the epsilon_margin finding & assignment
                epsilon_margin = 0.1;
                while ensurance_law_fulfilled == 0
                    epsilon_margin = 0.1 + epsilon_margin;
                    
                    for x_ij = 1:1:column_index_max
                        for y_ij = 1:1:row_index_max
                            d1 = sqrt((x_ij-x1)^2 + (y_ij-y1)^2);
                            d2 = sqrt((x_ij-x2)^2 + (y_ij-y2)^2);
                            if d1 + d2 + epsilon_margin < D
                                map_matrix(y_ij,x_ij) = 1;
                            end
                        end
                    end
                    
                    ensurance_law_fulfilled = 1;
                    for x_ij = 2:1:column_index_max-1
                        for y_ij = 2:1:row_index_max-1
                            if map_matrix(y_ij,x_ij) == 1
                                % Look for the neighbours states
                                if map_matrix(y_ij-1,x_ij) == 0 && map_matrix(y_ij,x_ij-1) == 0 && map_matrix(y_ij-1,x_ij-1) == 0   &&   map_matrix(y_ij+1,x_ij) == 0 && map_matrix(y_ij,x_ij+1) == 0 && map_matrix(y_ij+1,x_ij+1) == 0   &&   map_matrix(y_ij+1,x_ij-1) == 0 && map_matrix(y_ij-1,x_ij+1) == 0
                                    % No active neighbour so we need to increase E
                                    ensurance_law_fulfilled = 0;
                                end
                            end
                        end
                    end
                end
                % Step 3 : update all
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure_hil = uifigure('Visible', 'off');
            app.UIFigure_hil.Name = 'MATLAB App';
            app.y1_coord__EditField = uieditfield(app.UIFigure_hil, 'numeric');
            app.y1_coord__EditField.ValueChangedFcn = createCallbackFcn(app, @y1_assignment__ValueChangedFcn, true);
            app.y1_coordEditFieldLabel = uilabel(app.UIFigure_hil);
            app.y1_coordEditFieldLabel.Text = 'y1_coord';
            app.x1_coord__EditField = uieditfield(app.UIFigure_hil, 'numeric');
            app.x1_coord__EditField.ValueChangedFcn = createCallbackFcn(app, @x1_assignment__ValueChangedFcn, true);
            app.x1_coordEditFieldLabel = uilabel(app.UIFigure_hil);
            app.x1_coordEditFieldLabel.Text = 'x1_coord';
            app.test_lamp_robot_busy__stateCheckBox = uicheckbox(app.UIFigure_hil);
            app.test_lamp_robot_busy__stateCheckBox.ValueChangedFcn = createCallbackFcn(app, @test_lamp_robot_busy__stateCheckBoxValueChanged, true);
            app.test_lamp_robot_busy__stateCheckBox.Text = 'test_lamp_robot_busy_state';
            app.lamp_robot_busy_state = uilamp(app.UIFigure_hil);
            app.lamp_robot_busy_state.Tag = 'Robot_State';
            app.lamp_robot_busy_stateLabel = uilabel(app.UIFigure_hil);
            app.lamp_robot_busy_stateLabel.Text = 'lamp_robot_busy_state';
            app.compass_indicator = uiaeroheading(app.UIFigure_hil);
            app.compass_indicatorHeadingIndicatorLabel = uilabel(app.UIFigure_hil);
            app.compass_indicatorHeadingIndicatorLabel.Text = 'compass_indicator';
            app.turn_indicator = uiaeroturn(app.UIFigure_hil);
            app.turn_indicatorLabel = uilabel(app.UIFigure_hil);
            app.turn_indicatorLabel.Text = 'turn_indicator';
            app.heading_speed_indicator = uiaeroairspeed(app.UIFigure_hil);
            app.heading_speed_indicatorAirspeedIndicatorLabel = uilabel(app.UIFigure_hil);
            app.heading_speed_indicatorAirspeedIndicatorLabel.Text = 'heading_speed_indicator';
            app.test_heading_speed__indicatorSlider = uislider(app.UIFigure_hil);
            app.test_heading_speed__indicatorSlider.Limits = [0 400];
            app.test_heading_speed__indicatorSlider.ValueChangedFcn = createCallbackFcn(app, @test_heading_speed__indicatorSliderValueChanged, true);
            app.test_heading_speed_indicatorSliderLabel = uilabel(app.UIFigure_hil);
            app.test_heading_speed_indicatorSliderLabel.Text = 'test_heading_speed_indicator';
            app.test_turn_speed__indicatorSlider = uislider(app.UIFigure_hil);
            app.test_turn_speed__indicatorSlider.Limits = [-15 15];
            app.test_turn_speed__indicatorSlider.ValueChangedFcn = createCallbackFcn(app, @test_turn_speed__indicatorSliderValueChanged, true);
            app.test_turn_speed_indicatorSliderLabel = uilabel(app.UIFigure_hil);
            app.test_turn_speed_indicatorSliderLabel.Text = 'test_turn_speed_indicator';
            app.test_compass__indicatorSlider = uislider(app.UIFigure_hil);
            app.test_compass__indicatorSlider.Limits = [0 360];
            app.test_compass__indicatorSlider.ValueChangedFcn = createCallbackFcn(app, @test_compass__indicatorSliderValueChanged, true);
            app.test_compass_indicatorSliderLabel = uilabel(app.UIFigure_hil);
            app.test_compass_indicatorSliderLabel.Text = 'test_compass_indicator';
            app.test_turn_acceleration__indicatorSlider = uislider(app.UIFigure_hil);
            app.test_turn_acceleration__indicatorSlider.Limits = [-15 15];
            app.test_turn_acceleration__indicatorSlider.ValueChangedFcn = createCallbackFcn(app, @test_turn_acceleration__indicatorSliderValueChanged, true);
            app.test_turn_acceleration_indicatorSliderLabel = uilabel(app.UIFigure_hil);
            app.test_turn_acceleration_indicatorSliderLabel.Text = 'test_turn_acceleration_indicator';
            app.ADD_or_DELETE__DropDown = uidropdown(app.UIFigure_hil);
            app.ADD_or_DELETE__DropDown.Items = {'ADD a line', 'DELETE a line'};
            app.ADD_or_DELETE__DropDown.ValueChangedFcn = createCallbackFcn(app, @ADD_or_DELETE__ValueChangedFcn, true);
            app.ADD_or_DELETE__DropDown.Value = 'ADD a line';
            app.ADD_or_DELETEDropDownLabel = uilabel(app.UIFigure_hil);
            app.ADD_or_DELETEDropDownLabel.Text = 'ADD_or_DELETE';
            app.Execute__Button = uibutton(app.UIFigure_hil, 'push');
            app.Execute__Button.ButtonPushedFcn = createCallbackFcn(app, @Execute__ButtonPushedFcn, true);
            app.Execute__Button.Text = 'Execute !';
            app.Image = uiimage(app.UIFigure_hil);
            app.x2_coordEditFieldLabel = uilabel(app.UIFigure_hil);
            app.x2_coordEditFieldLabel.Text = 'x2_coord';
            app.x2_coord__EditField = uieditfield(app.UIFigure_hil, 'numeric');
            app.x2_coord__EditField.ValueChangedFcn = createCallbackFcn(app, @x2_assignment__ValueChangedFcn, true);
            app.y2_coordEditFieldLabel = uilabel(app.UIFigure_hil);
            app.y2_coordEditFieldLabel.Text = 'y2_coord';
            app.y2_coord__EditField = uieditfield(app.UIFigure_hil, 'numeric');
            app.y2_coord__EditField.ValueChangedFcn = createCallbackFcn(app, @y2_assignment__ValueChangedFcn, true);
            app.UITable = uitable(app.UIFigure_hil);
            app.UITable.ColumnName = {'Step N°'; 'x1_coord'; 'y1_coord'; 'x2_coord'; 'y2_coord'; 'action'};
            app.UITable.RowName = {};
            app.Warningx1x2Label = uilabel(app.UIFigure_hil);
            app.Warningx1x2Label.Text = 'Warning : x1 < x2';
            % Show the figure after all components are created
            app.UIFigure_hil.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_JK_HIL_IHM_v1_5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure_hil)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure_hil)
        end
    end
end