classdef HIL_IHM_v0_6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure_hil                    matlab.ui.Figure
        y_coord_top_leftEditField       matlab.ui.control.NumericEditField
        y_coord_top_leftEditFieldLabel  matlab.ui.control.Label
        x_coord_top_leftEditField       matlab.ui.control.NumericEditField
        x_coord_top_leftEditFieldLabel  matlab.ui.control.Label
        test_lamp_robot_busy_stateCheckBox  matlab.ui.control.CheckBox
        lamp_robot_busy_state           matlab.ui.control.Lamp
        lamp_robot_busy_stateLabel      matlab.ui.control.Label
        compass_indicator               Aero.ui.control.HeadingIndicator
        compass_indicatorHeadingIndicatorLabel  matlab.ui.control.Label
        turn_indicator                  Aero.ui.control.TurnCoordinator
        turn_indicatorLabel             matlab.ui.control.Label
        heading_speed_indicator         Aero.ui.control.AirspeedIndicator
        heading_speed_indicatorAirspeedIndicatorLabel  matlab.ui.control.Label
        test_heading_speed_indicatorSlider  matlab.ui.control.Slider
        test_heading_speed_indicatorSliderLabel  matlab.ui.control.Label
        test_turn_speed_indicatorSlider  matlab.ui.control.Slider
        test_turn_speed_indicatorSliderLabel  matlab.ui.control.Label
        test_compass_indicatorSlider    matlab.ui.control.Slider
        test_compass_indicatorSliderLabel  matlab.ui.control.Label
        test_turn_acceleration_indicatorSlider  matlab.ui.control.Slider
        test_turn_acceleration_indicatorSliderLabel  matlab.ui.control.Label
        ADD_or_DELETEDropDown           matlab.ui.control.DropDown
        ADD_or_DELETEDropDownLabel      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: test_lamp_robot_busy_stateCheckBox
        function test_lamp_robot_busy_stateCheckBoxValueChanged(app, event)
            lamp_robot_busy_state_value = app.test_lamp_robot_busy_stateCheckBox.Value;
            %%% Light the light when the test_lamp_robot_state is checked :
            %%% when the robot is busy : light red, otherwise light green.
            if lamp_robot_busy_state_value == 1
                app.lamp_robot_busy_state.Color = 'r';
            else
                app.lamp_robot_busy_state.Color = 'g';
            end
        end

        % Value changed function: test_heading_speed_indicatorSlider
        function test_heading_speed_indicatorSliderValueChanged(app, event)
            heading_speed_indicator_value = app.test_heading_speed_indicatorSlider.Value;
            %%% Update the heading_speed_indicator value with the slider's.
            app.heading_speed_indicator.Airspeed = heading_speed_indicator_value;
        end

        % Value changed function: test_turn_speed_indicatorSlider
        function test_turn_speed_indicatorSliderValueChanged(app, event)
            turn_indicator_acceleration_value = app.test_turn_speed_indicatorSlider.Value;
            %%% Update the turn_indicator acceleration value with the slider's.
            app.turn_indicator.Slip = -turn_indicator_acceleration_value;
        end

        % Value changed function: 
        % test_turn_acceleration_indicatorSlider
        function test_turn_acceleration_indicatorSliderValueChanged(app, event)
            turn_indicator_speed_value = app.test_turn_acceleration_indicatorSlider.Value;
            %%% Update the turn_indicator speed value with the slider's.
            app.turn_indicator.Turn = turn_indicator_speed_value;
        end

        % Value changed function: test_compass_indicatorSlider
        function test_compass_indicatorSliderValueChanged(app, event)
            compass_indicator_value = app.test_compass_indicatorSlider.Value;
            app.compass_indicator.Value = compass_indicator_value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure_hil = uifigure('Visible', 'off');
            app.UIFigure_hil.Name = 'MATLAB App';
            app.y_coord_top_leftEditField = uieditfield(app.UIFigure_hil, 'numeric');
            app.y_coord_top_leftEditFieldLabel = uilabel(app.UIFigure_hil);
            app.y_coord_top_leftEditFieldLabel.Text = 'y_coord_top_left';
            app.x_coord_top_leftEditField = uieditfield(app.UIFigure_hil, 'numeric');
            app.x_coord_top_leftEditFieldLabel = uilabel(app.UIFigure_hil);
            app.x_coord_top_leftEditFieldLabel.Text = 'x_coord_top_left';
            app.test_lamp_robot_busy_stateCheckBox = uicheckbox(app.UIFigure_hil);
            app.test_lamp_robot_busy_stateCheckBox.ValueChangedFcn = createCallbackFcn(app, @test_lamp_robot_busy_stateCheckBoxValueChanged, true);
            app.test_lamp_robot_busy_stateCheckBox.Text = 'test_lamp_robot_busy_state';
            app.lamp_robot_busy_state = uilamp(app.UIFigure_hil);
            app.lamp_robot_busy_state.Tag = 'Robot_State';
            app.lamp_robot_busy_stateLabel = uilabel(app.UIFigure_hil);
            app.lamp_robot_busy_stateLabel.Text = 'lamp_robot_busy_state';
            app.compass_indicator = uiaeroheading(app.UIFigure_hil);
            app.compass_indicatorHeadingIndicatorLabel = uilabel(app.UIFigure_hil);
            app.compass_indicatorHeadingIndicatorLabel.Text = 'compass_indicator';
            app.turn_indicator = uiaeroturn(app.UIFigure_hil);
            app.turn_indicatorLabel = uilabel(app.UIFigure_hil);
            app.turn_indicatorLabel.Text = 'turn_indicator';
            app.heading_speed_indicator = uiaeroairspeed(app.UIFigure_hil);
            app.heading_speed_indicatorAirspeedIndicatorLabel = uilabel(app.UIFigure_hil);
            app.heading_speed_indicatorAirspeedIndicatorLabel.Text = 'heading_speed_indicator';
            app.test_heading_speed_indicatorSlider = uislider(app.UIFigure_hil);
            app.test_heading_speed_indicatorSlider.Limits = [0 400];
            app.test_heading_speed_indicatorSlider.ValueChangedFcn = createCallbackFcn(app, @test_heading_speed_indicatorSliderValueChanged, true);
            app.test_heading_speed_indicatorSliderLabel = uilabel(app.UIFigure_hil);
            app.test_heading_speed_indicatorSliderLabel.Text = 'test_heading_speed_indicator';
            app.test_turn_speed_indicatorSlider = uislider(app.UIFigure_hil);
            app.test_turn_speed_indicatorSlider.Limits = [-15 15];
            app.test_turn_speed_indicatorSlider.ValueChangedFcn = createCallbackFcn(app, @test_turn_speed_indicatorSliderValueChanged, true);
            app.test_turn_speed_indicatorSliderLabel = uilabel(app.UIFigure_hil);
            app.test_turn_speed_indicatorSliderLabel.Text = 'test_turn_speed_indicator';
            app.test_compass_indicatorSlider = uislider(app.UIFigure_hil);
            app.test_compass_indicatorSlider.Limits = [0 360];
            app.test_compass_indicatorSlider.ValueChangedFcn = createCallbackFcn(app, @test_compass_indicatorSliderValueChanged, true);
            app.test_compass_indicatorSliderLabel = uilabel(app.UIFigure_hil);
            app.test_compass_indicatorSliderLabel.Text = 'test_compass_indicator';
            app.test_turn_acceleration_indicatorSlider = uislider(app.UIFigure_hil);
            app.test_turn_acceleration_indicatorSlider.Limits = [-15 15];
            app.test_turn_acceleration_indicatorSlider.ValueChangedFcn = createCallbackFcn(app, @test_turn_acceleration_indicatorSliderValueChanged, true);
            app.test_turn_acceleration_indicatorSliderLabel = uilabel(app.UIFigure_hil);
            app.test_turn_acceleration_indicatorSliderLabel.Text = 'test_turn_acceleration_indicator';
            app.ADD_or_DELETEDropDown = uidropdown(app.UIFigure_hil);
            app.ADD_or_DELETEDropDown.Items = {'ADD a line', 'DELETE a line'};
            app.ADD_or_DELETEDropDown.Value = 'ADD a line';
            app.ADD_or_DELETEDropDownLabel = uilabel(app.UIFigure_hil);
            app.ADD_or_DELETEDropDownLabel.Text = 'ADD_or_DELETE';
            % Show the figure after all components are created
            app.UIFigure_hil.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HIL_IHM_v0_6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure_hil)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure_hil)
        end
    end
end