classdef APP_JK_HIL_IHM_v10_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure_hil                    matlab.ui.Figure
        y1_coord__EditField             matlab.ui.control.NumericEditField
        y1_coordEditFieldLabel          matlab.ui.control.Label
        x1_coord__EditField             matlab.ui.control.NumericEditField
        x1_coordEditFieldLabel          matlab.ui.control.Label
        lamp_robot_busy_state           matlab.ui.control.Lamp
        lamp_robot_busy_stateLabel      matlab.ui.control.Label
        compass_indicator               Aero.ui.control.HeadingIndicator
        compass_indicatorHeadingIndicatorLabel  matlab.ui.control.Label
        turn_indicator                  Aero.ui.control.TurnCoordinator
        turn_indicatorLabel             matlab.ui.control.Label
        heading_speed_indicator         Aero.ui.control.AirspeedIndicator
        heading_speed_indicatorAirspeedIndicatorLabel  matlab.ui.control.Label
        test_heading_speed__indicatorSlider  matlab.ui.control.Slider
        test_heading_speed_indicatorSliderLabel  matlab.ui.control.Label
        test_turn_speed__indicatorSlider  matlab.ui.control.Slider
        test_turn_speed_indicatorSliderLabel  matlab.ui.control.Label
        test_compass__indicatorSlider   matlab.ui.control.Slider
        test_compass_indicatorSliderLabel  matlab.ui.control.Label
        test_turn_acceleration__indicatorSlider  matlab.ui.control.Slider
        test_turn_acceleration_indicatorSliderLabel  matlab.ui.control.Label
        Execute__Button                 matlab.ui.control.Button
        x2_coordEditFieldLabel          matlab.ui.control.Label
        x2_coord__EditField             matlab.ui.control.NumericEditField
        y2_coordEditFieldLabel          matlab.ui.control.Label
        y2_coord__EditField             matlab.ui.control.NumericEditField
        UITable                         matlab.ui.control.Table
        Warningx1x2Label                matlab.ui.control.Label
        ClearALL__Button                matlab.ui.control.Button
        Margin_errorEditFieldLabel_2    matlab.ui.control.Label
        Margin_error__EditField         matlab.ui.control.NumericEditField
        stepNtobedeletedEditFieldLabel  matlab.ui.control.Label
        stepNtobedeleted__EditField     matlab.ui.control.NumericEditField
        Deletetheselectedstep__Button   matlab.ui.control.Button
        AddGlobalWaypoint__Button       matlab.ui.control.Button
        x_positionEditFieldLabel        matlab.ui.control.Label
        x_positionEditField             matlab.ui.control.NumericEditField
        y_positionEditFieldLabel        matlab.ui.control.Label
        y_positionEditField             matlab.ui.control.NumericEditField
        AssignInitialPosition__Button   matlab.ui.control.Button
        orientationEditFieldLabel       matlab.ui.control.Label
        orientationEditField            matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: 
        % test_heading_speed__indicatorSlider
        function test_heading_speed__indicatorSliderValueChanged(app, event)
            heading_speed_indicator_value = app.test_heading_speed__indicatorSlider.Value;
            %%% Update the heading_speed_indicator value with the slider's.
            app.heading_speed_indicator.Airspeed = heading_speed_indicator_value;
        end

        % Value changed function: test_turn_speed__indicatorSlider
        function test_turn_speed__indicatorSliderValueChanged(app, event)
            turn_indicator_acceleration_value = app.test_turn_speed__indicatorSlider.Value;
            %%% Update the turn_indicator acceleration value with the slider's.
            app.turn_indicator.Slip = -turn_indicator_acceleration_value;
        end

        % Value changed function: 
        % test_turn_acceleration__indicatorSlider
        function test_turn_acceleration__indicatorSliderValueChanged(app, event)
            turn_indicator_speed_value = app.test_turn_acceleration__indicatorSlider.Value;
            %%% Update the turn_indicator speed value with the slider's.
            app.turn_indicator.Turn = turn_indicator_speed_value;
        end

        % Value changed function: test_compass__indicatorSlider
        function test_compass__indicatorSliderValueChanged(app, event)
            compass_indicator_value = app.test_compass__indicatorSlider.Value;
            app.compass_indicator.Value = compass_indicator_value;
        end

        % Button pushed function: Execute__Button
        function Execute__ButtonPushedFcn(app, event)
            app.lamp_robot_busy_state.Color = 'r';
            pause(0.025);
            
            step_count = 1 + evalin("base","step_count");
            assignin('base', 'step_count', step_count);
            x_operations_list = evalin("base","x_operations_list");
            y_operations_list = evalin("base","y_operations_list");
            step_operations_list = evalin("base","step_operations_list");
            
            % To enable matrices instead of lists to enhance performances
            temp_x_operations_list = [];
            temp_y_operations_list = [];


            % Save values by exporting them to Matlab, Rosbag and UITable
            % But first...
            x1 = app.x1_coord__EditField.Value;
            x2 = app.x2_coord__EditField.Value;
            y1 = app.y1_coord__EditField.Value;
            y2 = app.y2_coord__EditField.Value;
            % To avoid a cross to be displayed instead of a single dot
            if x1 == x2 && y1 == y2
                map_matrix = evalin("base","map_matrix");
                map_matrix(y1,x1) = 1;
                temp_x_operations_list = [temp_x_operations_list, x1];
                temp_y_operations_list = [temp_y_operations_list, y1];
            else                
                % Step 1 : Measure the distance between the 2 coordinates-Pythagore
                line_length = sqrt((x2-x1)^2 + (y2-y1)^2);
                D = line_length;
                % Step 2 : for each value of the matrix (map), check the distances
                map_matrix = evalin("base","map_matrix");
                [row_index_max, column_index_max] = size(map_matrix);
                % Automation of the epsilon_margin finding & assignment
                margin_error = app.Margin_error__EditField.Value;
                epsilon_margin = margin_error;
                ensurance_law_fulfilled = 0;
                while ensurance_law_fulfilled == 0
                    epsilon_margin = margin_error + epsilon_margin;
                    for x_ij = 1:1:column_index_max
                        for y_ij = 1:1:row_index_max
                            d1 = sqrt((x_ij-x1)^2 + (y_ij-y1)^2);
                            d2 = sqrt((x_ij-x2)^2 + (y_ij-y2)^2);
                            if d1 + d2 < D + epsilon_margin
                                map_matrix(y_ij,x_ij) = 1;
                                temp_x_operations_list = [temp_x_operations_list, x_ij];
                                temp_y_operations_list = [temp_y_operations_list, y_ij];
                            end
                        end
                    end


                    ensurance_law_fulfilled = 1;
                    for x_ij = 2:1:column_index_max-1
                        for y_ij = 2:1:row_index_max-1
                            if map_matrix(y_ij,x_ij) == 1
                                % Look for the neighbours states
                                if map_matrix(y_ij-1,x_ij) == 0 && map_matrix(y_ij,x_ij-1) == 0 && map_matrix(y_ij-1,x_ij-1) == 0   &&   map_matrix(y_ij+1,x_ij) == 0 && map_matrix(y_ij,x_ij+1) == 0 && map_matrix(y_ij+1,x_ij+1) == 0   &&   map_matrix(y_ij+1,x_ij-1) == 0 && map_matrix(y_ij-1,x_ij+1) == 0
                                    % No active neighbour so we need to increase E
                                    ensurance_law_fulfilled = 0;
                                end
                            end
                        end
                    end
                end
            end
                % Step 3 : update all
%            for x_ij = 1:1:column_index_max
%                for y_ij = 1:1:row_index_max
%                    if map_matrix(y_ij,x_ij) == 1
%                        map_matrix(y_ij,x_ij) = 0;
%                    else
%                        map_matrix(y_ij,x_ij) = 1;
%                    end
%                end
%            end
            addpath("C:\temp\")
            imwrite(map_matrix,'C:\temp\myImage.png','png');
            imshow(map_matrix, 'Parent', app.UIAxes);
            assignin('base', 'map_matrix', map_matrix)
            
            disp("temp:")
            disp(size(temp_x_operations_list))
            disp(size(temp_y_operations_list))
            x_operations_list{end+1} = temp_x_operations_list;
            y_operations_list{end+1} = temp_y_operations_list;
            step_operations_list = [step_operations_list, step_count];
            assignin('base', 'x_operations_list', x_operations_list);
            assignin('base', 'y_operations_list', y_operations_list);
            assignin('base', 'step_operations_list', step_operations_list);         
            
            
            % Build a historical solution to keep clues of what was done
            step_list = evalin("base","step_list");
            x1_coord_list = evalin("base","x1_coord_list");
            y1_coord_list = evalin("base","y1_coord_list");
            x2_coord_list = evalin("base","x2_coord_list");
            y2_coord_list = evalin("base","y2_coord_list");
            margin_error_list = evalin("base","margin_error_list");
            
            step_list = [step_list, step_count];
            x1_coord_list = [x1_coord_list, x1];
            y1_coord_list = [y1_coord_list, y1];
            x2_coord_list = [x2_coord_list, x2];
            y2_coord_list = [y2_coord_list, y2];
            margin_error_list = [margin_error_list, app.Margin_error__EditField.Value];
            
            assignin('base', 'step_list', step_list);
            assignin('base', 'x1_coord_list', x1_coord_list);
            assignin('base', 'y1_coord_list', y1_coord_list);
            assignin('base', 'x2_coord_list', x2_coord_list);
            assignin('base', 'y2_coord_list', y2_coord_list);
            assignin('base', 'margin_error_list', margin_error_list);
            
            % Assign values to the UITable
            tdata = table(step_list',x1_coord_list',y1_coord_list',x2_coord_list',y2_coord_list',margin_error_list','VariableNames',{'step_list','x1_coord_list','y1_coord_list','x2_coord_list','y2_coord_list','margin_error_list'});
            app.UITable = uitable(app.UITable.Parent,'Data',tdata);
            app.UITable.Position = [1134 166 454 432];
            
            pause(0.025);
            app.lamp_robot_busy_state.Color = 'g';
        end

        % Button pushed function: ClearALL__Button
        function ClearALL_Button__ButtonPushedFcn(app, event)
            app.lamp_robot_busy_state.Color = 'r';
            pause(0.025);
            
            % Reset the map, display it and save it
            map_matrix = evalin("base","map_matrix");
            map_matrix = 0.*map_matrix;
            imshow(map_matrix, 'Parent', app.UIAxes);
            assignin('base', 'map_matrix', map_matrix)
            
            step_list = [];
            x1_coord_list = [];
            y1_coord_list = [];
            x2_coord_list = [];
            y2_coord_list = [];
            margin_error_list = [];
            tdata = table(step_list',x1_coord_list',y1_coord_list',x2_coord_list',y2_coord_list','VariableNames',{'step_list','x1_coord_list','y1_coord_list','x2_coord_list','y2_coord_list'});
            app.UITable = uitable(app.UITable.Parent,'Data',tdata);
            app.UITable.Position = [1134 166 454 432];
            assignin('base', 'step_list', step_list);
            assignin('base', 'x1_coord_list', x1_coord_list);
            assignin('base', 'y1_coord_list', y1_coord_list);
            assignin('base', 'x2_coord_list', x2_coord_list);
            assignin('base', 'y2_coord_list', y2_coord_list);
            assignin('base', 'margin_error_list', margin_error_list);
            
            x_operations_list = {};
            y_operations_list = {};
            step_operations_list = [];
            assignin('base', 'x_operations_list', x_operations_list);
            assignin('base', 'y_operations_list', y_operations_list);
            assignin('base', 'step_operations_list', step_operations_list);
            
            step_count = 0;
            assignin('base', 'step_count', step_count);
            
            pause(0.025);
            app.lamp_robot_busy_state.Color = 'g';
        end

        % Button pushed function: Deletetheselectedstep__Button
        function Deletetheselectedstep__Button__ButtonPushedFcn(app, event)
            tic
            
            
            app.lamp_robot_busy_state.Color = 'r';
            pause(0.025);
            
            step_to_be_deleted = app.stepNtobedeleted__EditField.Value;
            
            x_operations_list = evalin("base","x_operations_list");
            y_operations_list = evalin("base","y_operations_list");
            step_operations_list = evalin("base","step_operations_list");
            max_index = length(step_operations_list);

            map_matrix = evalin("base","map_matrix");
           
            
            new_step_operations_list = step_operations_list;
            % The action must be undone...
            for step_index = 1:1:max_index
                disp("Etape 1")
                disp(step_index)
                % Reset the values linked to this step
                if step_operations_list(step_index) == step_to_be_deleted
                    disp("Etape 2")
                    max_pixel_index = length(x_operations_list{step_index});
                    for pixel_index = 1:1:max_pixel_index
                        disp(size(y_operations_list))
                        map_matrix(y_operations_list{step_index}(pixel_index), x_operations_list{step_index}(pixel_index)) = 0;
                    end
                    x_operations_list = x_operations_list([1:step_index-1, step_index+1:end]);
                    y_operations_list = y_operations_list([1:step_index-1, step_index+1:end]);
                    new_step_operations_list = new_step_operations_list([1:step_index-1, step_index+1:end]);
                end
            end
            step_operations_list = new_step_operations_list;
            
            
            imshow(map_matrix, 'Parent', app.UIAxes);
            assignin('base', 'map_matrix', map_matrix)
            
            assignin('base', 'x_operations_list', x_operations_list);
            assignin('base', 'y_operations_list', y_operations_list);
            assignin('base', 'step_operations_list', step_operations_list);


            step_list = evalin("base","step_list");
            x1_coord_list = evalin("base","x1_coord_list");
            y1_coord_list = evalin("base","y1_coord_list");
            x2_coord_list = evalin("base","x2_coord_list");
            y2_coord_list = evalin("base","y2_coord_list");
            margin_error_list = evalin("base","margin_error_list");
            max_step_index = length(step_list);
            for step_index = 1:1:max_step_index-1
                disp("Etape 3")
                disp(step_index)
                if step_list(step_index) == step_to_be_deleted
                    step_list = step_list([1:step_index-1, step_index+1:end]);
                    x1_coord_list = x1_coord_list([1:step_index-1, step_index+1:end]);
                    y1_coord_list = y1_coord_list([1:step_index-1, step_index+1:end]);
                    x2_coord_list = x2_coord_list([1:step_index-1, step_index+1:end]);
                    y2_coord_list = y2_coord_list([1:step_index-1, step_index+1:end]);
                    margin_error_list = margin_error_list([1:step_index-1, step_index+1:end]);
                end
            end
            % In case the element to be deleted is the last one remaining
            if length(step_list) == 1 && step_list(end) == step_to_be_deleted
                step_list = [];
                x1_coord_list = [];
                y1_coord_list = [];
                x2_coord_list = [];
                y2_coord_list = [];
                margin_error_list = [];        
                % In case the element to be deleted is the last in the list
            elseif  step_list(end) == step_to_be_deleted
                step_list = step_list(1:end-1);
                x1_coord_list = x1_coord_list(1:end-1);
                y1_coord_list = y1_coord_list(1:end-1);
                x2_coord_list = x2_coord_list(1:end-1);
                y2_coord_list = y2_coord_list(1:end-1);
                margin_error_list = margin_error_list(1:end-1);
            end
            tdata = table(step_list',x1_coord_list',y1_coord_list',x2_coord_list',y2_coord_list',margin_error_list','VariableNames',{'step_list','x1_coord_list','y1_coord_list','x2_coord_list','y2_coord_list','margin_error_list'});
            app.UITable = uitable(app.UITable.Parent,'Data',tdata);
            app.UITable.Position = [1134 166 454 432];
            assignin('base', 'step_list', step_list);
            assignin('base', 'x1_coord_list', x1_coord_list);
            assignin('base', 'y1_coord_list', y1_coord_list);
            assignin('base', 'x2_coord_list', x2_coord_list);
            assignin('base', 'y2_coord_list', y2_coord_list);
            assignin('base', 'margin_error_list', margin_error_list);
            
            pause(0.025);
            app.lamp_robot_busy_state.Color = 'g';
            
            
            disp("Etapes finies")
            toc
        end

        % Button pushed function: AddGlobalWaypoint__Button
        function AddGlobalWaypoint__Button__ButtonPushedFcn(app, event)
            waypoints = evalin("base","waypoints");
            waypoints = [waypoints; app.x_positionEditField.Value, app.y_positionEditField.Value];
            assignin('base', 'waypoints', waypoints);
        end

        % Button pushed function: AssignInitialPosition__Button
        function AssignInitialPosition__Button_ButtonPushedFcn(app, event)
            initPose = [app.x_positionEditField.Value, app.y_positionEditField.Value, app.orientationEditField.Value];
            assignin('base', 'initPose', initPose);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure_hil = uifigure('Visible', 'off');
            app.UIFigure_hil.Name = 'MATLAB App';
            app.y1_coord__EditField = uieditfield(app.UIFigure_hil, 'numeric');
            app.y1_coordEditFieldLabel = uilabel(app.UIFigure_hil);
            app.y1_coordEditFieldLabel.Text = 'y1_coord';
            app.x1_coord__EditField = uieditfield(app.UIFigure_hil, 'numeric');
            app.x1_coordEditFieldLabel = uilabel(app.UIFigure_hil);
            app.x1_coordEditFieldLabel.Text = 'x1_coord';
            app.lamp_robot_busy_state = uilamp(app.UIFigure_hil);
            app.lamp_robot_busy_state.Tag = 'Robot_State';
            app.lamp_robot_busy_stateLabel = uilabel(app.UIFigure_hil);
            app.lamp_robot_busy_stateLabel.Text = 'lamp_robot_busy_state';
            app.compass_indicator = uiaeroheading(app.UIFigure_hil);
            app.compass_indicatorHeadingIndicatorLabel = uilabel(app.UIFigure_hil);
            app.compass_indicatorHeadingIndicatorLabel.Text = 'compass_indicator';
            app.turn_indicator = uiaeroturn(app.UIFigure_hil);
            app.turn_indicatorLabel = uilabel(app.UIFigure_hil);
            app.turn_indicatorLabel.Text = 'turn_indicator';
            app.heading_speed_indicator = uiaeroairspeed(app.UIFigure_hil);
            app.heading_speed_indicatorAirspeedIndicatorLabel = uilabel(app.UIFigure_hil);
            app.heading_speed_indicatorAirspeedIndicatorLabel.Text = 'heading_speed_indicator';
            app.test_heading_speed__indicatorSlider = uislider(app.UIFigure_hil);
            app.test_heading_speed__indicatorSlider.Limits = [0 400];
            app.test_heading_speed__indicatorSlider.ValueChangedFcn = createCallbackFcn(app, @test_heading_speed__indicatorSliderValueChanged, true);
            app.test_heading_speed_indicatorSliderLabel = uilabel(app.UIFigure_hil);
            app.test_heading_speed_indicatorSliderLabel.Text = 'test_heading_speed_indicator';
            app.test_turn_speed__indicatorSlider = uislider(app.UIFigure_hil);
            app.test_turn_speed__indicatorSlider.Limits = [-15 15];
            app.test_turn_speed__indicatorSlider.ValueChangedFcn = createCallbackFcn(app, @test_turn_speed__indicatorSliderValueChanged, true);
            app.test_turn_speed_indicatorSliderLabel = uilabel(app.UIFigure_hil);
            app.test_turn_speed_indicatorSliderLabel.Text = 'test_turn_speed_indicator';
            app.test_compass__indicatorSlider = uislider(app.UIFigure_hil);
            app.test_compass__indicatorSlider.Limits = [0 360];
            app.test_compass__indicatorSlider.ValueChangedFcn = createCallbackFcn(app, @test_compass__indicatorSliderValueChanged, true);
            app.test_compass_indicatorSliderLabel = uilabel(app.UIFigure_hil);
            app.test_compass_indicatorSliderLabel.Text = 'test_compass_indicator';
            app.test_turn_acceleration__indicatorSlider = uislider(app.UIFigure_hil);
            app.test_turn_acceleration__indicatorSlider.Limits = [-15 15];
            app.test_turn_acceleration__indicatorSlider.ValueChangedFcn = createCallbackFcn(app, @test_turn_acceleration__indicatorSliderValueChanged, true);
            app.test_turn_acceleration_indicatorSliderLabel = uilabel(app.UIFigure_hil);
            app.test_turn_acceleration_indicatorSliderLabel.Text = 'test_turn_acceleration_indicator';
            app.Execute__Button = uibutton(app.UIFigure_hil, 'push');
            app.Execute__Button.ButtonPushedFcn = createCallbackFcn(app, @Execute__ButtonPushedFcn, true);
            app.Execute__Button.Text = 'Execute !';
            app.x2_coordEditFieldLabel = uilabel(app.UIFigure_hil);
            app.x2_coordEditFieldLabel.Text = 'x2_coord';
            app.x2_coord__EditField = uieditfield(app.UIFigure_hil, 'numeric');
            app.y2_coordEditFieldLabel = uilabel(app.UIFigure_hil);
            app.y2_coordEditFieldLabel.Text = 'y2_coord';
            app.y2_coord__EditField = uieditfield(app.UIFigure_hil, 'numeric');
            app.UITable = uitable(app.UIFigure_hil);
            app.UITable.ColumnName = {'Step N°'; 'x1_coord'; 'y1_coord'; 'x2_coord'; 'y2_coord'};
            app.UITable.RowName = {};
            app.Warningx1x2Label = uilabel(app.UIFigure_hil);
            app.Warningx1x2Label.Text = 'Warning : x1 < x2 ; Also keep in mind that Matlab indexes begin on the top-left hand corner';
            app.ClearALL__Button = uibutton(app.UIFigure_hil, 'push');
            app.ClearALL__Button.ButtonPushedFcn = createCallbackFcn(app, @ClearALL_Button__ButtonPushedFcn, true);
            app.ClearALL__Button.Text = 'Clear ALL !';
            app.Margin_errorEditFieldLabel_2 = uilabel(app.UIFigure_hil);
            app.Margin_errorEditFieldLabel_2.Text = 'Margin_error';
            app.Margin_error__EditField = uieditfield(app.UIFigure_hil, 'numeric');
            app.Margin_error__EditField.Value = 0.01;
            app.stepNtobedeletedEditFieldLabel = uilabel(app.UIFigure_hil);
            app.stepNtobedeletedEditFieldLabel.Text = 'step N° to be deleted';
            app.stepNtobedeleted__EditField = uieditfield(app.UIFigure_hil, 'numeric');
            app.Deletetheselectedstep__Button = uibutton(app.UIFigure_hil, 'push');
            app.Deletetheselectedstep__Button.ButtonPushedFcn = createCallbackFcn(app, @Deletetheselectedstep__Button__ButtonPushedFcn, true);
            app.Deletetheselectedstep__Button.Text = 'Delete the selected step !';
            app.AddGlobalWaypoint__Button = uibutton(app.UIFigure_hil, 'push');
            app.AddGlobalWaypoint__Button.ButtonPushedFcn = createCallbackFcn(app, @AddGlobalWaypoint__Button__ButtonPushedFcn, true);
            app.AddGlobalWaypoint__Button.Text = 'Add Global Waypoint';
            app.x_positionEditFieldLabel = uilabel(app.UIFigure_hil);
            app.x_positionEditFieldLabel.Text = 'x_position';
            app.x_positionEditField = uieditfield(app.UIFigure_hil, 'numeric');
            app.y_positionEditFieldLabel = uilabel(app.UIFigure_hil);
            app.y_positionEditFieldLabel.Text = 'y_position';
            app.y_positionEditField = uieditfield(app.UIFigure_hil, 'numeric');
            app.AssignInitialPosition__Button = uibutton(app.UIFigure_hil, 'push');
            app.AssignInitialPosition__Button.ButtonPushedFcn = createCallbackFcn(app, @AssignInitialPosition__Button_ButtonPushedFcn, true);
            app.AssignInitialPosition__Button.Text = 'Assign Initial Position';
            app.orientationEditFieldLabel = uilabel(app.UIFigure_hil);
            app.orientationEditFieldLabel.Text = 'orientation';
            app.orientationEditField = uieditfield(app.UIFigure_hil, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure_hil);
            title(app.UIAxes, 'map matrix')
            xlabel(app.UIAxes, 'Columns')
            ylabel(app.UIAxes, 'Rows')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure_hil.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_JK_HIL_IHM_v10_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure_hil)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure_hil)
        end
    end
end